classdef binocularMRI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		BinocularMRIUIFigure            matlab.ui.Figure
		BinocularMRIStimulatorV113Label  matlab.ui.control.Label
		TabGroup                        matlab.ui.container.TabGroup
		BinocularTab                    matlab.ui.container.Tab
		MappingPanel                    matlab.ui.container.Panel
		Label                           matlab.ui.control.Label
		meridiansMap                    matlab.ui.control.CheckBox
		timeMap                         matlab.ui.control.NumericEditField
		TemporalFrequencyEditField_2Label_6  matlab.ui.control.Label
		sizeMap                         matlab.ui.control.NumericEditField
		TemporalFrequencyEditField_2Label_5  matlab.ui.control.Label
		repeatsMap                      matlab.ui.control.NumericEditField
		TemporalFrequencyEditField_2Label_4  matlab.ui.control.Label
		wedgeMap                        matlab.ui.control.NumericEditField
		TemporalFrequencyEditField_2Label_3  matlab.ui.control.Label
		contrastMap                     matlab.ui.control.NumericEditField
		TemporalFrequencyEditField_2Label_2  matlab.ui.control.Label
		tFMap                           matlab.ui.control.NumericEditField
		TemporalFrequencyEditField_2Label  matlab.ui.control.Label
		MapButton                       matlab.ui.control.Button
		FellowEyeDropDown               matlab.ui.control.DropDown
		FellowEyeDropDownLabel          matlab.ui.control.Label
		FocusTestTimesEditField         matlab.ui.control.EditField
		FocusTestTimesEditFieldLabel    matlab.ui.control.Label
		FocusTestSizeEditField          matlab.ui.control.NumericEditField
		FocusTestSizeLabel              matlab.ui.control.Label
		SFModTimeEditField              matlab.ui.control.NumericEditField
		SFModTimeEditFieldLabel         matlab.ui.control.Label
		PixelDensityEditField           matlab.ui.control.NumericEditField
		PixelDensityEditFieldLabel      matlab.ui.control.Label
		DistancecmEditField             matlab.ui.control.NumericEditField
		DistancecmEditFieldLabel        matlab.ui.control.Label
		SubjectNameEditField            matlab.ui.control.EditField
		SubjectNameEditFieldLabel       matlab.ui.control.Label
		TemporalFrequencyEditField      matlab.ui.control.NumericEditField
		TemporalFrequencyEditFieldLabel  matlab.ui.control.Label
		RepeatsEditField                matlab.ui.control.NumericEditField
		RepeatsEditFieldLabel           matlab.ui.control.Label
		StimInterstimTimesEditField     matlab.ui.control.EditField
		StimInterstimTimesEditFieldLabel  matlab.ui.control.Label
		SFModEditField                  matlab.ui.control.NumericEditField
		SFModEditFieldLabel             matlab.ui.control.Label
		AngleModEditField               matlab.ui.control.NumericEditField
		AngleModEditFieldLabel          matlab.ui.control.Label
		FlashRateEditField              matlab.ui.control.NumericEditField
		FlashRateEditFieldLabel         matlab.ui.control.Label
		SpatialFrequencyEditField       matlab.ui.control.NumericEditField
		SpatialFrequencyEditFieldLabel  matlab.ui.control.Label
		StimulusTypeListBox             matlab.ui.control.ListBox
		StimulusTypeListBoxLabel        matlab.ui.control.Label
		RightRGBEditField               matlab.ui.control.EditField
		RightRGBEditFieldLabel          matlab.ui.control.Label
		LeftRGBEditField                matlab.ui.control.EditField
		LeftRGBEditFieldLabel           matlab.ui.control.Label
		BackgroundEditField             matlab.ui.control.EditField
		BackgroundEditFieldLabel        matlab.ui.control.Label
		StereoModeEditField             matlab.ui.control.NumericEditField
		StereoModeEditFieldLabel        matlab.ui.control.Label
		FellowContrast                  matlab.ui.control.NumericEditField
		TestButton                      matlab.ui.control.Button
		debugCheckBox                   matlab.ui.control.CheckBox
		QuitButton                      matlab.ui.control.Button
		RunButton                       matlab.ui.control.Button
		Tab                             matlab.ui.container.Tab
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: RunButton, TestButton
		function RunButtonPushed(app, event)

			if strcmpi(event.Source.Text,'Test')
				in.test = true;
			else
				in.test = false;
			end
			
			in.name = app.SubjectNameEditField.Value;
			in.distance = app.DistancecmEditField.Value;
			in.ppcm = app.PixelDensityEditField.Value;
			in.fellowEye = app.FellowEyeDropDown.Value;
			in.fellowContrast = app.FellowContrast.Value;
			in.stereoMode = app.StereoModeEditField.Value;
			in.bg = str2num(app.BackgroundEditField.Value);
			in.left = str2num(app.LeftRGBEditField.Value);
			in.right = str2num(app.RightRGBEditField.Value);
			in.type = app.StimulusTypeListBox.Value;
			in.sf = app.SpatialFrequencyEditField.Value;
			in.tf = app.TemporalFrequencyEditField.Value;
			in.flash = app.FlashRateEditField.Value;
			in.anglemod = app.AngleModEditField.Value;
			in.sfmod = app.SFModEditField.Value;
			in.sfmodtime = app.SFModTimeEditField.Value;
			in.repeats =  app.RepeatsEditField.Value;
			in.times = str2num(app.StimInterstimTimesEditField.Value);
			in.debug = app.debugCheckBox.Value;
			in.focusSize = app.FocusTestSizeEditField.Value;
			in.focusTime = str2num(app.FocusTestTimesEditField.Value);

			in.mapsize = app.sizeMap.Value;
			in.maptf = app.tFMap.Value;
			in.mapcontrast = app.contrastMap.Value;
			in.mapwedge = app.wedgeMap.Value;
			in.maprepeats = app.repeatsMap.Value;
			in.maptime = app.timeMap.Value;
			in.mapmeridians = app.meridiansMap.Value;
			
			binocularStimulation(in);
		end

		% Button pushed function: QuitButton
		function QuitButtonPushed(app, event)
			delete(app);
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.BinocularMRIUIFigure = uifigure('Visible', 'off');
			app.BinocularMRIUIFigure.Name = 'Binocular MRI';
			app.TabGroup = uitabgroup(app.BinocularMRIUIFigure);
			app.BinocularTab = uitab(app.TabGroup);
			app.BinocularTab.Title = 'Binocular';
			app.RunButton = uibutton(app.BinocularTab, 'push');
			app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
			app.RunButton.Text = 'Run!';
			app.QuitButton = uibutton(app.BinocularTab, 'push');
			app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
			app.QuitButton.Text = 'Quit';
			app.debugCheckBox = uicheckbox(app.BinocularTab);
			app.debugCheckBox.Text = 'debug';
			app.TestButton = uibutton(app.BinocularTab, 'push');
			app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
			app.TestButton.Text = 'Test';
			app.FellowContrast = uieditfield(app.BinocularTab, 'numeric');
			app.FellowContrast.Value = 0.15;
			app.StereoModeEditFieldLabel = uilabel(app.BinocularTab);
			app.StereoModeEditFieldLabel.Text = 'Stereo Mode';
			app.StereoModeEditField = uieditfield(app.BinocularTab, 'numeric');
			app.StereoModeEditField.Value = 8;
			app.BackgroundEditFieldLabel = uilabel(app.BinocularTab);
			app.BackgroundEditFieldLabel.Text = 'Background';
			app.BackgroundEditField = uieditfield(app.BinocularTab, 'text');
			app.BackgroundEditField.Value = '0  0  0';
			app.LeftRGBEditFieldLabel = uilabel(app.BinocularTab);
			app.LeftRGBEditFieldLabel.Text = 'Left RGB';
			app.LeftRGBEditField = uieditfield(app.BinocularTab, 'text');
			app.LeftRGBEditField.Value = '0.72  0  0';
			app.RightRGBEditFieldLabel = uilabel(app.BinocularTab);
			app.RightRGBEditFieldLabel.Text = 'Right RGB';
			app.RightRGBEditField = uieditfield(app.BinocularTab, 'text');
			app.RightRGBEditField.Value = '0  0.3  1';
			app.StimulusTypeListBoxLabel = uilabel(app.BinocularTab);
			app.StimulusTypeListBoxLabel.Text = 'Stimulus Type';
			app.StimulusTypeListBox = uilistbox(app.BinocularTab);
			app.StimulusTypeListBox.Items = {'Checkerboard', 'Mapping', 'Spiral', 'Radial', 'Circular'};
			app.StimulusTypeListBox.Value = 'Checkerboard';
			app.SpatialFrequencyEditFieldLabel = uilabel(app.BinocularTab);
			app.SpatialFrequencyEditFieldLabel.Text = 'Spatial Frequency';
			app.SpatialFrequencyEditField = uieditfield(app.BinocularTab, 'numeric');
			app.SpatialFrequencyEditField.Value = 0.59;
			app.FlashRateEditFieldLabel = uilabel(app.BinocularTab);
			app.FlashRateEditFieldLabel.Text = 'Flash Rate';
			app.FlashRateEditField = uieditfield(app.BinocularTab, 'numeric');
			app.AngleModEditFieldLabel = uilabel(app.BinocularTab);
			app.AngleModEditFieldLabel.Text = 'Angle Mod';
			app.AngleModEditField = uieditfield(app.BinocularTab, 'numeric');
			app.AngleModEditField.Value = 0.1;
			app.SFModEditFieldLabel = uilabel(app.BinocularTab);
			app.SFModEditFieldLabel.Text = 'SF Mod';
			app.SFModEditField = uieditfield(app.BinocularTab, 'numeric');
			app.SFModEditField.Value = 0.81;
			app.StimInterstimTimesEditFieldLabel = uilabel(app.BinocularTab);
			app.StimInterstimTimesEditFieldLabel.Text = 'Stim / Inter-stim Time (s)';
			app.StimInterstimTimesEditField = uieditfield(app.BinocularTab, 'text');
			app.StimInterstimTimesEditField.Value = '16 16';
			app.RepeatsEditFieldLabel = uilabel(app.BinocularTab);
			app.RepeatsEditFieldLabel.Text = 'Repeats';
			app.RepeatsEditField = uieditfield(app.BinocularTab, 'numeric');
			app.RepeatsEditField.Value = 4;
			app.TemporalFrequencyEditFieldLabel = uilabel(app.BinocularTab);
			app.TemporalFrequencyEditFieldLabel.Text = 'Temporal Frequency';
			app.TemporalFrequencyEditField = uieditfield(app.BinocularTab, 'numeric');
			app.SubjectNameEditFieldLabel = uilabel(app.BinocularTab);
			app.SubjectNameEditFieldLabel.Text = 'Subject Name';
			app.SubjectNameEditField = uieditfield(app.BinocularTab, 'text');
			app.SubjectNameEditField.Value = 'Test';
			app.DistancecmEditFieldLabel = uilabel(app.BinocularTab);
			app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
			app.DistancecmEditField = uieditfield(app.BinocularTab, 'numeric');
			app.DistancecmEditField.Value = 225;
			app.PixelDensityEditFieldLabel = uilabel(app.BinocularTab);
			app.PixelDensityEditFieldLabel.Text = 'Pixel Density';
			app.PixelDensityEditField = uieditfield(app.BinocularTab, 'numeric');
			app.PixelDensityEditField.Value = 35.2;
			app.SFModTimeEditFieldLabel = uilabel(app.BinocularTab);
			app.SFModTimeEditFieldLabel.Text = 'SF Mod Time';
			app.SFModTimeEditField = uieditfield(app.BinocularTab, 'numeric');
			app.SFModTimeEditField.Value = 0.02;
			app.FocusTestSizeLabel = uilabel(app.BinocularTab);
			app.FocusTestSizeLabel.Text = 'Focus-Test Size';
			app.FocusTestSizeEditField = uieditfield(app.BinocularTab, 'numeric');
			app.FocusTestSizeEditField.Value = 0.4;
			app.FocusTestTimesEditFieldLabel = uilabel(app.BinocularTab);
			app.FocusTestTimesEditFieldLabel.Text = 'Focus-Test Times';
			app.FocusTestTimesEditField = uieditfield(app.BinocularTab, 'text');
			app.FocusTestTimesEditField.Value = '2    5';
			app.FellowEyeDropDownLabel = uilabel(app.BinocularTab);
			app.FellowEyeDropDownLabel.Text = 'Fellow Eye';
			app.FellowEyeDropDown = uidropdown(app.BinocularTab);
			app.FellowEyeDropDown.Items = {'Left', 'Right'};
			app.FellowEyeDropDown.Value = 'Left';
			app.MappingPanel = uipanel(app.BinocularTab);
			app.MappingPanel.Title = 'Mapping';
			app.MapButton = uibutton(app.MappingPanel, 'push');
			app.MapButton.Enable = 'off';
			app.MapButton.Text = 'Map!';
			app.TemporalFrequencyEditField_2Label = uilabel(app.MappingPanel);
			app.TemporalFrequencyEditField_2Label.Text = 'Temporal Frequency';
			app.tFMap = uieditfield(app.MappingPanel, 'numeric');
			app.tFMap.Value = 8;
			app.TemporalFrequencyEditField_2Label_2 = uilabel(app.MappingPanel);
			app.TemporalFrequencyEditField_2Label_2.Text = 'Contrast';
			app.contrastMap = uieditfield(app.MappingPanel, 'numeric');
			app.contrastMap.Value = 1;
			app.TemporalFrequencyEditField_2Label_3 = uilabel(app.MappingPanel);
			app.TemporalFrequencyEditField_2Label_3.Text = 'Wedge Size (deg)';
			app.wedgeMap = uieditfield(app.MappingPanel, 'numeric');
			app.wedgeMap.Value = 45;
			app.TemporalFrequencyEditField_2Label_4 = uilabel(app.MappingPanel);
			app.TemporalFrequencyEditField_2Label_4.Text = 'Repeats';
			app.repeatsMap = uieditfield(app.MappingPanel, 'numeric');
			app.repeatsMap.Value = 8;
			app.TemporalFrequencyEditField_2Label_5 = uilabel(app.MappingPanel);
			app.TemporalFrequencyEditField_2Label_5.Text = 'Size';
			app.sizeMap = uieditfield(app.MappingPanel, 'numeric');
			app.sizeMap.Value = 7.5;
			app.TemporalFrequencyEditField_2Label_6 = uilabel(app.MappingPanel);
			app.TemporalFrequencyEditField_2Label_6.Text = 'Time';
			app.timeMap = uieditfield(app.MappingPanel, 'numeric');
			app.timeMap.Value = 42;
			app.meridiansMap = uicheckbox(app.MappingPanel);
			app.meridiansMap.Text = 'Only Meridians?';
			app.meridiansMap.Value = true;
			app.Label = uilabel(app.MappingPanel);
			app.Label.Text = 'Meridian mapping uses protocol from Greenberg 2012: 18 seconds blank, 16 alternations of 18 seconds vertical and horizontal, then 18 secs blank';
			app.Tab = uitab(app.TabGroup);
			app.Tab.Title = '...';
			app.BinocularMRIStimulatorV113Label = uilabel(app.BinocularMRIUIFigure);
			app.BinocularMRIStimulatorV113Label.Text = 'Binocular MRI Stimulator V1.13';
			% Show the figure after all components are created
			app.BinocularMRIUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = binocularMRI

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.BinocularMRIUIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.BinocularMRIUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.BinocularMRIUIFigure)
		end
	end
end